classdef MAIN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        CantileverBeamOptimizerLabel  matlab.ui.control.Label
        btn_generate                  matlab.ui.control.Button
        btn_finish                    matlab.ui.control.Button
        uipanel4                      matlab.ui.container.Panel
        txt_path                      matlab.ui.control.Label
        txt_log                       matlab.ui.control.TextArea
        uipanel3                      matlab.ui.container.Panel
        txt_length                    matlab.ui.control.NumericEditField
        txt_axial                     matlab.ui.control.NumericEditField
        ddown_numweights              matlab.ui.control.DropDown
        slider_axial                  matlab.ui.control.Slider
        text9                         matlab.ui.control.Label
        text8                         matlab.ui.control.Label
        text7                         matlab.ui.control.Label
        text6                         matlab.ui.control.Label
    end

    
    methods (Access = private)
% =========================================================================
% =========================================================================
%                             TEAM NAME   
% =========================================================================
% =========================================================================

% Developed by: <Team Members>
% GROUP: ABC
% University of Ottawa
% Mechanical Engineering
% Latest Revision: 06/12/2022 by Nathaniel Mailhot
% MATLAB 
% =========================================================================

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function MAIN_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>           
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to MAIN (see VARARGIN)          
            % Choose default command line output for MAIN
            handles.output = hObject;        
            % Update handles structure
            guidata(hObject, handles);          
            %Set the default values on the GUI. It is recommended to choose a valid set
            %of default values as a starting point when the program launches.
            clc
            %Set the window title with the group identification:
            set(handles.figure1,'Name','Group ABC | Optimization GUI | uOttawa CAD 2023');  
            %Add the 'subfunctions' folder to the path so that subfunctions can be
            %accessed
            addpath('Subfunctions');
        end

        % Button pushed function: btn_finish
        function btn_finish_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to BTN_Finish (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close gcf
        end

        % Callback function
        function BTN_Generate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to BTN_Generate (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if(isempty(handles))
                Wrong_File(app);
            else
    
                %Show the results on the GUI.

            
                set(handles.TXT_log,'String',S); %write the string into the textbox
                set(handles.TXT_path,'String',log_file); %show the path of the log file
                set(handles.TXT_path,'Visible','on');
            end
        end

        % Button pushed function: btn_generate
        function btn_generatePushed(app, event)
            %%
            %Get the design parameters from the interface 
            %Some UI (sliders) return numerical values directly
            %Other UI (dropdown) return char, must be converted
            %----------- Read values from GUI ----------------%
            %returns axial force as numerical value directly
            axial_force = app.slider_axial.Value;
            %returns num_weights as a numerical value, after conversion
            number_of_weights = str2double(app.ddown_numweights.Value);
            %returns shaft length as double
            shaft_length = app.txt_length.Value;

            %----------- Call design code function(s) --------%
            %The sample design calculations performed by calling Design_code.m
            Design_code(axial_force, number_of_weights, shaft_length)
            
            %----------- Display results from log file -------%
            % Create path variable. Mac/Linux require forward slash for
            % directory paths. Windows agnostic, but is typically back slash.
            directory_prefix_string = extractBefore(pwd, "groupABC"); %pwd gets current directory, then the directory suffix is extracted.
            log_file = strcat(directory_prefix_string, '/groupABC/Log/groupABC_LOG.TXT');
            fid = fopen(log_file,'r'); %Open the log file for reading
            log_contents=char(fread(fid)'); %Read the file into a string
            fclose(fid);
            
            app.txt_log.Value = log_contents;
            app.txt_path.Text = log_file;
            app.txt_log.Enable = 'On';
            
        end

        % Value changed function: txt_axial
        function txt_axialValueChanged(app, event)
            app.slider_axial.Value = app.txt_axial.Value;
        end

        % Value changed function: slider_axial
        function slider_axialValueChanged(app, event)
            app.txt_axial.Value = app.slider_axial.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'MAIN';
            app.figure1.Tag = 'figure1';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Input Parameters';
            app.uipanel3.Tag = 'uipanel3';
            app.text6 = uilabel(app.uipanel3);
            app.text6.Tag = 'text6';
            app.text6.Text = {'Axial Force (N):'; '            '};
            app.text7 = uilabel(app.uipanel3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Number of weights on end of shaft (10 kg per weight):';
            app.text8 = uilabel(app.uipanel3);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Length of Shaft (mm):';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Range:  [0 ; 50]';
            app.slider_axial = uislider(app.uipanel3);
            app.slider_axial.Limits = [0 50];
            app.slider_axial.ValueChangedFcn = createCallbackFcn(app, @slider_axialValueChanged, true);
            app.slider_axial.Value = 25;
            app.ddown_numweights = uidropdown(app.uipanel3);
            app.ddown_numweights.Items = {'5', '10', '15', '20'};
            app.ddown_numweights.Value = '5';
            app.txt_axial = uieditfield(app.uipanel3, 'numeric');
            app.txt_axial.Limits = [0 50];
            app.txt_axial.ValueChangedFcn = createCallbackFcn(app, @txt_axialValueChanged, true);
            app.txt_axial.Value = 25;
            app.txt_length = uieditfield(app.uipanel3, 'numeric');
            app.txt_length.Limits = [0.001 50];
            app.txt_length.Value = 10;
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Output Log';
            app.uipanel4.Tag = 'uipanel4';
            app.txt_log = uitextarea(app.uipanel4);
            app.txt_log.Tag = 'TXT_log';
            app.txt_log.Enable = 'off';
            app.txt_log.Value = {'Chose a set of desired design input parameters, then...'; ''; 'Click the generate button!'};
            app.txt_path = uilabel(app.uipanel4);
            app.txt_path.Tag = 'TXT_path';
            app.txt_path.Text = 'LOG.TXT';
            app.btn_finish = uibutton(app.figure1, 'push');
            app.btn_finish.ButtonPushedFcn = createCallbackFcn(app, @btn_finish_Callback, true);
            app.btn_finish.Tag = 'BTN_Finish';
            app.btn_finish.Text = 'CLOSE';
            app.btn_generate = uibutton(app.figure1, 'push');
            app.btn_generate.ButtonPushedFcn = createCallbackFcn(app, @btn_generatePushed, true);
            app.btn_generate.Text = 'GENERATE';
            app.CantileverBeamOptimizerLabel = uilabel(app.figure1);
            app.CantileverBeamOptimizerLabel.Text = 'Cantilever Beam Optimizer';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAIN(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)MAIN_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end